classdef my_p4_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Slider_3  matlab.ui.control.Slider
        Label_2   matlab.ui.control.Label
        Slider_2  matlab.ui.control.Slider
        Panel     matlab.ui.container.Panel
        Label     matlab.ui.control.Label
    end

    
    properties (Access = private)
        a % Description
        a1
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.a=app.Slider_2.Value;
            app.a1=app.Slider_3.Value;
            app.Label.FontSize=round(app.a1);
            app.Label.Text=num2str(app.a);
        end

        % Callback function
        function SliderValueChanging(app, event)

        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            app.a = event.Value;
            app.Label.Text=num2str(round(app.a));
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            app.a1 = event.Value;
            app.Label.FontSize=round(app.a1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '0';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 9];
            app.Slider_2.MajorTicks = [0 1 2 3 4 5 6 7 8 9];
            app.Slider_2.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.MinorTicks = [0 1 2 3 4 5 6 7 8 9];
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [8 120];
            app.Slider_3.MajorTicks = [8 16 24 32 40 48 56 64 72 80 88 96 104 112 120];
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.Slider_3.MinorTicks = [8 16 24 32 40 48 56 64 72 80 88 96 104 112 120];
            app.Slider_3.Value = 24;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = my_p4_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end