classdef MP_w12_M1_202301638_Kimgyuhyeon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        redButton   matlab.ui.control.Button
        blueButton  matlab.ui.control.Button
        UIAxes2     matlab.ui.control.UIAxes
        UIAxes1     matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            axis(app.UIAxes1,[-2 2 -2 2]);
            patch(app.UIAxes1,[-1 1 1 -1 -1],[-1 -1 1 1 -1],'red');
            patch(app.UIAxes2,[-1 1 0 -1],[-1 -1 1 -1],'red');
            
            axis(app.UIAxes2,[-2 2 -2 2]);
            axis (app.UIAxes1,"equal");
            axis (app.UIAxes2,"equal");
            
        end

        % Button pushed function: blueButton
        function blueButtonPushed(app, event)
            patch(app.UIAxes1,[-1 1 1 -1 -1],[-1 -1 1 1 -1],'blue');
            patch(app.UIAxes2,[-1 1 0 -1],[-1 -1 1 -1],'blue');
            
        end

        % Button pushed function: redButton
        function redButtonPushed(app, event)
            patch(app.UIAxes1,[-1 1 1 -1 -1],[-1 -1 1 1 -1],'red');
            patch(app.UIAxes2,[-1 1 0 -1],[-1 -1 1 -1],'red');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.blueButton = uibutton(app.UIFigure, 'push');
            app.blueButton.ButtonPushedFcn = createCallbackFcn(app, @blueButtonPushed, true);
            app.blueButton.Text = 'blue';
            app.redButton = uibutton(app.UIFigure, 'push');
            app.redButton.ButtonPushedFcn = createCallbackFcn(app, @redButtonPushed, true);
            app.redButton.Text = 'red';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MP_w12_M1_202301638_Kimgyuhyeon

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end