classdef MP_w12_M2_202301638_Kimgyuhyeon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HelloUIFigure     matlab.ui.Figure
        BIG_NAUTHYButton  matlab.ui.control.Button
        HELLOButton       matlab.ui.control.Button
        OMGButton         matlab.ui.control.Button
        ButtonStop        matlab.ui.control.Button
        ButtonPlay        matlab.ui.control.Button
        ButtonPause       matlab.ui.control.Button
    end

    
    properties (Access = private)
        a
        y
        Fs
        paused 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            
        end

        % Button pushed function: ButtonPause
        function ButtonPausePushed(app, event)
            pause(app.a);
            app.paused=true;
            
        end

        % Button pushed function: ButtonStop
        function ButtonStopPushed(app, event)
            stop(app.a)
            
        end

        % Button pushed function: ButtonPlay
        function ButtonPlayPushed(app, event)
            
            if app.paused
                resume(app.a)
            else
                play(app.a);
            end
            app.paused=false;
            
            
        end

        % Button pushed function: BIG_NAUTHYButton
        function BIG_NAUTHYButtonPushed(app, event)
            [app.y,app.Fs]=audioread("bignauthy.mp3");
            app.a=audioplayer(app.y,app.Fs);
            app.paused=false;
            
        end

        % Button pushed function: HELLOButton
        function HELLOButtonPushed(app, event)
            [app.y,app.Fs]=audioread("hello_adele.mp3");
            app.a=audioplayer(app.y,app.Fs);
            app.paused=false;
            
        end

        % Button pushed function: OMGButton
        function OMGButtonPushed(app, event)
            [app.y,app.Fs]=audioread("omg.mp3");
            app.a=audioplayer(app.y,app.Fs);
            app.paused=false;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HelloUIFigure = uifigure('Visible', 'off');
            app.HelloUIFigure.Name = 'Hello';
            app.HelloUIFigure.Icon = fullfile(pathToMLAPP, 'baseline_sentiment_satisfied_alt_black_48dp.png');
            app.ButtonPause = uibutton(app.HelloUIFigure, 'push');
            app.ButtonPause.ButtonPushedFcn = createCallbackFcn(app, @ButtonPausePushed, true);
            app.ButtonPause.Icon = 'baseline_pause_black_48dp.png';
            app.ButtonPause.Text = '';
            app.ButtonPlay = uibutton(app.HelloUIFigure, 'push');
            app.ButtonPlay.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlayPushed, true);
            app.ButtonPlay.Icon = 'baseline_play_arrow_black_48dp.png';
            app.ButtonPlay.Text = '';
            app.ButtonStop = uibutton(app.HelloUIFigure, 'push');
            app.ButtonStop.ButtonPushedFcn = createCallbackFcn(app, @ButtonStopPushed, true);
            app.ButtonStop.Icon = 'baseline_stop_black_48dp.png';
            app.ButtonStop.Text = '';
            app.OMGButton = uibutton(app.HelloUIFigure, 'push');
            app.OMGButton.ButtonPushedFcn = createCallbackFcn(app, @OMGButtonPushed, true);
            app.OMGButton.Text = 'OMG';
            app.HELLOButton = uibutton(app.HelloUIFigure, 'push');
            app.HELLOButton.ButtonPushedFcn = createCallbackFcn(app, @HELLOButtonPushed, true);
            app.HELLOButton.Text = 'HELLO';
            app.BIG_NAUTHYButton = uibutton(app.HelloUIFigure, 'push');
            app.BIG_NAUTHYButton.ButtonPushedFcn = createCallbackFcn(app, @BIG_NAUTHYButtonPushed, true);
            app.BIG_NAUTHYButton.Text = 'BIG_NAUTHY';
            % Show the figure after all components are created
            app.HelloUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MP_w12_M2_202301638_Kimgyuhyeon

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HelloUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HelloUIFigure)
        end
    end
end